classdef composite_beam_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StresscalculatorforcompositebeamandcombinedloadingLabel  matlab.ui.control.Label
        IntroductionPanel            matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        UserGuideButton              matlab.ui.control.Button
        TheoryManualButton           matlab.ui.control.Button
        HelpManualButton             matlab.ui.control.Button
        TestCasesButton              matlab.ui.control.Button
        CreatedByButton              matlab.ui.control.Button
        InputPanel                   matlab.ui.container.Panel
        LoadingParametersPanel       matlab.ui.container.Panel
        BendingMomentinkNmEditFieldLabel  matlab.ui.control.Label
        BendingMomentinkNmEditField  matlab.ui.control.NumericEditField
        AxialLoadinkNEditFieldLabel  matlab.ui.control.Label
        AxialLoadinkNEditField       matlab.ui.control.NumericEditField
        Label_2                      matlab.ui.control.Label
        loadHintButton               matlab.ui.control.Button
        xpositioninmmEditFieldLabel  matlab.ui.control.Label
        xpositioninmmEditField       matlab.ui.control.NumericEditField
        ypositioninmmEditFieldLabel  matlab.ui.control.Label
        ypositioninmmEditField       matlab.ui.control.NumericEditField
        BeamParametersPanel          matlab.ui.container.Panel
        NumberoflayersSpinnerLabel   matlab.ui.control.Label
        NumberoflayersSpinner        matlab.ui.control.Spinner
        DetailsofeachlayerPanel      matlab.ui.container.Panel
        layerHintButton              matlab.ui.control.Button
        CalculateButton              matlab.ui.control.Button
    end

    %Properties contains all the global variables of this file
    properties (Access = private)
        outputApp=[];%Variable to interact with output window
        
        layerCount=0;%To track the count of total layers in beam
        
        materialLabel=[];%Stores UILabel for material 
        modulusLabel=[];%Stores UILabel asking for modulus of layer i
        layerModulus=[];%Conatins UIedit which stores value for modulus input by user
        crossSectionLabel=[];%Stores UILabel for each layer asking user for Cross section type
        crossSectionDropDown=[]%Stores uidropdowns. user input for cross section for each layer stores in its value
        dimensionLabelsForB=[];%Stores uiLabels for all width parameters
        layerDimensionsForB=[];%stores uiEdit for each width
        dimensionLabelsForH=[];%stores label for each height parameter
        layerDimensionsForH=[];%stores uiEdit to store value for each height
        
        deletePerformed=false;%To check whether a deletion was performed or not
    end
    
    %Below contains all global functions 
    methods (Access = private)
        %Function to create labels
        function label=returnLabel(app, text,index)
            label=uilabel(app.DetailsofeachlayerPanel);
            label.FontSize = 13;
            label.FontAngle = 'italic';
            label.Text = {sprintf(text,index)};
            return 
        end
        %Function to create edit fields
        function edit=returnEdit(app)
            edit = uieditfield(app.DetailsofeachlayerPanel, 'numeric');
            edit.FontSize = 13;
            edit.FontAngle = 'italic';
            edit.Limits = [0 Inf];
            
            return
        end
        %Function to create cross section dropdown
        function dropDown=returnDropDown(app)
            dropDown = uidropdown(app.DetailsofeachlayerPanel);
            dropDown.Items = {'Rectangular', 'T - Cross section', 'I - Cross section'};
            dropDown.Value='Rectangular';
            dropDown.FontSize=13;
            dropDown.FontName = 'Times New Roman';            
            return
        end
        
        %Function for callback for dropdown change
        function dropDownChangeCall(app,index)
            positionDimension(app,index);
        end
        
        %Function to create  labels and edits
        function dim=returnDimensionLabelAndEdit(app)
            bLabel=[];
            hLabel=[];
            bEdit=[];
            hEdit=[];
            tipText={'Click on i button to get more information on crossesection dimentions'};
            
            for i=1:3
                bLabel=[bLabel, returnLabel(app,'b%d (in mm)',i)];
                bLabel(i).Tooltip=tipText;
                hLabel=[hLabel, returnLabel(app,'h%d (in mm)',i)];
                hLabel(i).Tooltip=tipText;
                bEdit=[bEdit, returnEdit(app)];
                hEdit=[hEdit, returnEdit(app)];
                hLabel(i).Visible='off';
                bLabel(i).Visible='off';
                hEdit(i).Visible='off';
                bEdit(i).Visible='off';
            end 
            
                dim=[bLabel;hLabel;bEdit;hEdit];
              
            return
        end
        
        %Function to position and change dimension labels and edits
        function positionDimension(app,index)
            dropDownVal=string(app.crossSectionDropDown(index).Value);
            y_cs=app.crossSectionDropDown(index).Position(2);
            
            app.dimensionLabelsForB(index,1).Visible='on';
            app.dimensionLabelsForH(index,1).Visible='on';
            app.layerDimensionsForB(index,1).Visible='on';
            app.layerDimensionsForH(index,1).Visible='on';
            %changing visibility of dimensions based on cross section
            if dropDownVal=='Rectangular'
               %Rectangular
               for i=2:3
                     app.dimensionLabelsForB(index,i).Visible='off';
                     app.dimensionLabelsForH(index,i).Visible='off';
                     app.layerDimensionsForB(index,i).Visible='off';
                     app.layerDimensionsForH(index,i).Visible='off';
               
               end
    
            elseif dropDownVal=='T - Cross section'
                    %T cross section
                    app.dimensionLabelsForB(index,3).Visible='off';
                    app.dimensionLabelsForH(index,3).Visible='off';
                    app.layerDimensionsForB(index,3).Visible='off';
                    app.layerDimensionsForH(index,3).Visible='off';
                    
                    app.dimensionLabelsForB(index,2).Visible='on';
                     app.dimensionLabelsForH(index,2).Visible='on';
                     app.layerDimensionsForB(index,2).Visible='on';
                     app.layerDimensionsForH(index,2).Visible='on';
            else
                for i=2:3
                     app.dimensionLabelsForB(index,i).Visible='on';
                     app.dimensionLabelsForH(index,i).Visible='on';
                     app.layerDimensionsForB(index,i).Visible='on';
                     app.layerDimensionsForH(index,i).Visible='on';
               
               end
            end
            %Positioning the dimensions
            for i=1:3                   
                app.dimensionLabelsForB(index,i).Position=[35+(i-1)*120,y_cs-30,70,22];
                app.layerDimensionsForB(index,i).Position=[35+70+(i-1)*120,y_cs-30,40,22];
                app.dimensionLabelsForH(index,i).Position=[35+(i-1)*120,y_cs-30-30,70,22];
                app.layerDimensionsForH(index,i).Position=[35+70+(i-1)*120,y_cs-30-30,40,22];
                
            end

        end
        
        %Function to position layers. Called when any change happens
        function positionAllLayers(app)
            app.materialLabel(app.layerCount).Position=[16, 124,68,22];
            app.modulusLabel(app.layerCount).Position=[20, 124-24,280,22];
            app.layerModulus(app.layerCount).Position=[260,124-24,130,22];
            app.crossSectionLabel(app.layerCount).Position=[20,124-54,260,22];
            app.crossSectionDropDown(app.layerCount).Position=[260,124-54,130,22];
            positionDimension(app,app.layerCount);
            
            for  i=app.layerCount-1:-1:1
                y_pos=app.materialLabel(i+1).Position(2) +144;
                app.materialLabel(i).Position=[16, y_pos, 68, 22 ];
                app.modulusLabel(i).Position=[20, y_pos-24,280,22];
                app.layerModulus(i).Position=[260, y_pos-24, 130,22];
                app.crossSectionLabel(i).Position=[20,y_pos-54,260,22];
                app.crossSectionDropDown(i).Position=[260,y_pos-54,130,22];
                positionDimension(app,i);
            end
        end
        
        %Function to add layers on top of previous one( and bottom in the
        %app display)
        function addLayer(app, newLayerCount)
            for i=app.layerCount+1:newLayerCount
                    
                matLabel=returnLabel(app,'Layer %d ',i);
                matLabel.FontWeight='bold';
                modLabel=returnLabel(app,'Youngs Elasticity Modulus, E%d (in MPa)',i);
                modEdit=returnEdit(app);
                csLabel=returnLabel(app,'Cross section of layer %d',i);
                csDropDown=returnDropDown(app);
                
                if(i<=size(app.materialLabel))
                    
                    app.materialLabel(i)=matLabel;
                    app.modulusLabel(i)=modLabel;
                    app.layerModulus(i)=modEdit;
                    app.crossSectionLabel(i)=csLabel;
                    app.crossSectionDropDown(i)=csDropDown;
                    
                else
                    app.materialLabel=[app.materialLabel ,matLabel];
                    app.modulusLabel=[app.modulusLabel, modLabel];
                    app.layerModulus=[app.layerModulus,modEdit];
                    app.crossSectionLabel=[app.crossSectionLabel , csLabel];
                    app.crossSectionDropDown=[app.crossSectionDropDown, csDropDown];
                
                    dim=returnDimensionLabelAndEdit(app,i);
                    app.dimensionLabelsForB=[app.dimensionLabelsForB ;[dim(1,1),dim(1,2),dim(1,3)]];
                    app.dimensionLabelsForH=[app.dimensionLabelsForH ;[dim(2,1),dim(2,2),dim(2,3)]];
                    app.layerDimensionsForB=[app.layerDimensionsForB;[dim(3,1),dim(3,2),dim(3,3)]];
                    app.layerDimensionsForH=[app.layerDimensionsForH;[dim(4,1),dim(4,2),dim(4,3)]];
                    
                end
                
                app.crossSectionDropDown(i).ValueChangedFcn=@(csDropDown,event)dropDownChangeCall(app,i);
            end
                        
        end
        
        %Function to delete layers from top ( and from bottom in the app
        %display)
        function deleteLayer(app,oldLayerCount,newLayerCount)
            app.deletePerformed=true;
            for i=oldLayerCount:-1:newLayerCount+1     
                delete(app.materialLabel(i));
                app.materialLabel(i)=[];
                delete(app.modulusLabel(i));
                app.modulusLabel(i)=[];
                delete(app.layerModulus(i));
                app.layerModulus(i)=[];
                delete(app.crossSectionLabel(i));
                app.crossSectionLabel(i)=[];
                delete(app.crossSectionDropDown(i));
                app.crossSectionDropDown(i)=[];
                
                for j=1:3
                    app.dimensionLabelsForB(i,j).Visible='off';
                    app.dimensionLabelsForH(i,j).Visible='off';
                    app.layerDimensionsForB(i,j).Visible='off';
                    app.layerDimensionsForH(i,j).Visible='off';
                end
                
            end
        end
        
        %Function to check dimensions and modulus is zero or negative 
        function err=checkDimension(app,b,h,E,index)
            if(b<=0 || h<=0)
                uialert(app.UIFigure,sprintf('Dimensions cannot be zero or negative. Check cross section dimensions for layer %d',index),'Error in Cross section dimensions','Icon','error');
                err=-1;
            
            elseif(E<=0)
                uialert(app.UIFigure,sprintf('Modulus of elasticity cannot be zero or negative. Check details for layer %d',index),'Error in Cross section Elasticity modulus','Icon','error');
                err=-1;
            else
                err=index;
            end
            
            
            return;
        end
        
        %Function to generate output parameters for output file
        function [E,N,b,h,H,b_max,face_color,n,sum,moment,axialLoad,axialX,axialY]=outputCode(app)
            N=0;%Stores total number of width/height parameters
            %Copying values input by user into variables that are to be
            %returned
            axialLoad=app.AxialLoadinkNEditField.Value;
            moment=app.BendingMomentinkNmEditField.Value;
            axialX=app.xpositioninmmEditField.Value;
            axialY=app.ypositioninmmEditField.Value;
            
            %Counting total number of width/height parameters
            for i=1:app.layerCount
                if(string(app.crossSectionDropDown(i).Value)=='Rectangular')
                    N=N+1;
                elseif(string(app.crossSectionDropDown(i).Value)=='T - Cross section')
                    N=N+2;
                else
                    N=N+3;
                end
            end
            
            %Height width and modulus and ratio of modulus. based on coder's code 
            b = zeros(1,N);
            E = zeros(1,N);
            h = zeros(1,N);
            H=0;%Total height
            n=zeros(1,N);% Ratio of elastic modulus with respect to first
            n(1)=1;
            %Storing y position of top of layer relative to bottom layer
            sum=zeros(1,N);
            sum(1)=0;
            face_color=zeros(1:N);%Stores face color codes to differentiate between adjacent layers
            b_max=0;%Bounding box width is max width among all cross sections
            j=1;
            %based on cross section, filling up the details and checking
            %for errors
            for i=1:app.layerCount
                %col=randi([1,7],1);
                col=rem(i,7)+1;
                if(string(app.crossSectionDropDown(i).Value)=='Rectangular')
                    b(j)=app.layerDimensionsForB(i,1).Value;
                    if(b(j)>b_max)
                        b_max=b(j);
                    end
                    h(j)=app.layerDimensionsForH(i,1).Value;
                    E(j)=app.layerModulus(i).Value;
                    error=checkDimension(app,b(j),h(j),E(j),i);
                    if(error<0)
                        N=error;
                        return;
                    end
                    face_color(j)=col;
                    H=H+h(j);
                    j=j+1;
                elseif(string(app.crossSectionDropDown(i).Value)=='T - Cross section')
                    for k=1:2
                        b(j)=app.layerDimensionsForB(i,k).Value;
                        if(b(j)>b_max)
                            b_max=b(j);
                        end
                        h(j)=app.layerDimensionsForH(i,k).Value;
                        E(j)=app.layerModulus(i).Value;
                        face_color(j)=col;
                        error=checkDimension(app,b(j),h(j),E(j),i);
                        if(error<0)
                            N=error;
                            return;
                        end
                        H=H+h(j);
                        j=j+1;
                    end
                else
                    for k=1:3
                        b(j)=app.layerDimensionsForB(i,k).Value;
                        if(b(j)>b_max)
                            b_max=b(j);
                        end
                        h(j)=app.layerDimensionsForH(i,k).Value;
                        E(j)=app.layerModulus(i).Value;
                        face_color(j)=col;
                        error=checkDimension(app,b(j),h(j),E(j),i);
                        if(error<0)
                            N=error;
                            return;
                        end
                        H=H+h(j);
                        j=j+1;
                    end
                end
               
            end
            
            %Checking whether axial load lies on cross section or not
            y_pos=-H/2;%from ground
            if(axialY<-H/2 || axialY>H/2)
                % out of bounds in y direction
                uialert(app.UIFigure,sprintf('The y position of axial load is lying outside of beam cross section. Check your axial load position with respect to bounding box center or dimensions of layers. Note all dimensions and position are in mm units.'),'Axial Load out of boundary','Icon','error');
                N=-1;
                return;
            end
            for i=1:N
                y_pos=y_pos+h(i);
                if y_pos>axialY
                    if( axialX>b(i)/2 || axialX<-b(i)/2)
                        %Out of x bounds
                        uialert(app.UIFigure,sprintf('The x position of axial load is lying outside of beam cross section. Check your axial load position with respect to bounding box center or dimensions of layers. Note all dimensions and position are in mm units.'),'Axial Load out of boundary','Icon','error');
                        N=-1;
                        return;
                    end
                    break
                end
            end
            
            %position of top of any layer based on bottom most (first
            %layer)
            for i=2:N
                sum(i) = sum(i-1) + h(i-1);
                n(i) = E(i)/E(1);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %assigning default values to pre defined fields in the app
            app.layerCount=0;
            
            app.AxialLoadinkNEditField.Value=0;
            app.BendingMomentinkNmEditField.Value=0;
            app.NumberoflayersSpinner.Value=0;
            
            app.deletePerformed=false;
        end

        % Value changed function: NumberoflayersSpinner
        function NumberoflayersSpinnerValueChanged(app, event)
            newLayerCount = app.NumberoflayersSpinner.Value;
            %Adding or deleting layers based on change in number of layers
            
            if(newLayerCount<app.layerCount)
                deleteLayer(app,app.layerCount,newLayerCount);
            else
                addLayer(app,newLayerCount);
            end
            
            app.layerCount=newLayerCount;
            
            if (newLayerCount<=0)
                app.CalculateButton.Enable=false;
            else
                app.CalculateButton.Enable=true;
                app.positionAllLayers();
            end
            
            
        end

        % Button pushed function: UserGuideButton
        function UserGuideButtonPushed(app, event)
            %User guide
            web("manuals/6b662efe-34d0-11eb-8b25-0cc47a792c0a_id_6b662efe-34d0-11eb-8b25-0cc47a792c0a.html",'-new','-notoolbar');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %Close function executed when user attempts to close the app
            uiresume(app.UIFigure);
            delete(app.outputApp);
            delete(app)
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %This is called when calculate button is pressed. Output app is
            %opend, Calculated parameters are sent to output app 
            
            app.CalculateButton.Enable='off';
            
            %uiprogressdlg prevents user to make changes in input while the
            %output window is still open
            d=uiprogressdlg(app.UIFigure,'Title','Output Window','Indeterminate',"on");
            d.Message= {' Calculating output parameters'};
            [E,N,b,h,H,b_max,face_color,n,sum,moment,axialLoad,axialX,axialY]=outputCode(app);
            if(N>0)
               app.outputApp=output;
                app.outputApp.initialize(app.UIFigure,app.layerCount,E,N,b,h,H,b_max,face_color,n,sum,moment,axialLoad,axialX,axialY);
                d.Message= {' Output window is open. Close it to get access to this window '};
                uiwait(app.UIFigure); 
                %uiwait pauses the input program until resumed
            end
            
            app.CalculateButton.Enable='on';
            close(d);
            app.outputApp=[];
        end

        % Button pushed function: layerHintButton
        function layerHintButtonPushed(app, event)
            %Hint for layer cross section
            web("manuals/2c5661f4-3474-11eb-8b25-0cc47a792c0a_id_2c5661f4-3474-11eb-8b25-0cc47a792c0a.html",'-new','-notoolbar','-noaddressbox');
        end

        % Button pushed function: loadHintButton
        function loadHintButtonPushed(app, event)
            %Hint for axial eccentric load location 
            web("manuals/a3f75506-34d3-11eb-8b25-0cc47a792c0a_id_a3f75506-34d3-11eb-8b25-0cc47a792c0a.html",'-new','-notoolbar','-noaddressbox');
        end

        % Button pushed function: HelpManualButton
        function HelpManualButtonPushed(app, event)
            %Help manual
            web("manuals/7ece517e-34d0-11eb-8b25-0cc47a792c0a_id_7ece517e-34d0-11eb-8b25-0cc47a792c0a.html",'-new','-notoolbar','-noaddressbox');
        end

        % Button pushed function: TheoryManualButton
        function TheoryManualButtonPushed(app, event)
            %Theory manual
            web("manuals/ME 321_MODB_Stress in beams_Theory MANUAL.html");
        end

        % Button pushed function: TestCasesButton
        function TestCasesButtonPushed(app, event)
            web("manuals/Test_Case_Manual_18110023_ME-321_Project.html");
        end

        % Button pushed function: CreatedByButton
        function CreatedByButtonPushed(app, event)
           %Created by  webpage
            web("manuals/createdBy.html");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StresscalculatorforcompositebeamandcombinedloadingLabel = uilabel(app.UIFigure);
            app.StresscalculatorforcompositebeamandcombinedloadingLabel.Text = 'Stress calculator for composite beam and combined loading';
            app.IntroductionPanel = uipanel(app.UIFigure);
            app.IntroductionPanel.Title = 'Introduction';
            app.Label = uilabel(app.IntroductionPanel);
            app.Label.Text = {'This app lets you calculate and visualise the'; 'stress on a composite beam due to '; 'combined load on it. Insert the dimensions '; 'and youngs modulus of each cross-section. '; 'As well as the parameters of the loading '; 'on the beam.  Refer the manuals provided '; 'below to know the theory behind the app''s '; 'working and a guide to get used to this app.'};
            app.UserGuideButton = uibutton(app.IntroductionPanel, 'push');
            app.UserGuideButton.ButtonPushedFcn = createCallbackFcn(app, @UserGuideButtonPushed, true);
            app.UserGuideButton.Tooltip = {'Don''t know how to being. Click here to get used to the app.'};
            app.UserGuideButton.Text = 'User Guide';
            app.TheoryManualButton = uibutton(app.IntroductionPanel, 'push');
            app.TheoryManualButton.ButtonPushedFcn = createCallbackFcn(app, @TheoryManualButtonPushed, true);
            app.TheoryManualButton.Tooltip = {'Don''t know what this is about. Click here to know the theory behind the calculation.'};
            app.TheoryManualButton.Text = 'Theory Manual';
            app.HelpManualButton = uibutton(app.IntroductionPanel, 'push');
            app.HelpManualButton.ButtonPushedFcn = createCallbackFcn(app, @HelpManualButtonPushed, true);
            app.HelpManualButton.Tooltip = {'To know how to proceed, click here'};
            app.HelpManualButton.Text = 'Help Manual';
            app.TestCasesButton = uibutton(app.IntroductionPanel, 'push');
            app.TestCasesButton.ButtonPushedFcn = createCallbackFcn(app, @TestCasesButtonPushed, true);
            app.TestCasesButton.Tooltip = {'Click here to see test cases for the app.'};
            app.TestCasesButton.Text = 'Test Cases';
            app.CreatedByButton = uibutton(app.IntroductionPanel, 'push');
            app.CreatedByButton.ButtonPushedFcn = createCallbackFcn(app, @CreatedByButtonPushed, true);
            app.CreatedByButton.Tooltip = {'Want to know who made this app. Click here '};
            app.CreatedByButton.Text = 'Created By';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input Panel';
            app.LoadingParametersPanel = uipanel(app.InputPanel);
            app.LoadingParametersPanel.Tooltip = {''};
            app.LoadingParametersPanel.Title = 'Loading Parameters';
            app.BendingMomentinkNmEditFieldLabel = uilabel(app.LoadingParametersPanel);
            app.BendingMomentinkNmEditFieldLabel.Text = 'Bending Moment (in kNm)';
            app.BendingMomentinkNmEditField = uieditfield(app.LoadingParametersPanel, 'numeric');
            app.BendingMomentinkNmEditField.Tooltip = {'Bending moment along z direction is considered positive ( refer bending moment section in theory manual)'};
            app.AxialLoadinkNEditFieldLabel = uilabel(app.LoadingParametersPanel);
            app.AxialLoadinkNEditFieldLabel.Tooltip = {'Positive means tensile load and negative means compressive load'};
            app.AxialLoadinkNEditFieldLabel.Text = 'Axial Load (in kN)';
            app.AxialLoadinkNEditField = uieditfield(app.LoadingParametersPanel, 'numeric');
            app.AxialLoadinkNEditField.Tooltip = {'Positive axial load means tensile load and negative means compressive load'};
            app.Label_2 = uilabel(app.LoadingParametersPanel);
            app.Label_2.Tooltip = {'Position of point of action of axial loading with respect to center of bounding box of beam cross section . Click on i button to know more about these parameters.'};
            app.Label_2.Text = 'Position of point of action of axial loading ';
            app.loadHintButton = uibutton(app.LoadingParametersPanel, 'push');
            app.loadHintButton.ButtonPushedFcn = createCallbackFcn(app, @loadHintButtonPushed, true);
            app.loadHintButton.Tooltip = {'To know more about axial load positioning Click Here'};
            app.loadHintButton.Text = 'i';
            app.xpositioninmmEditFieldLabel = uilabel(app.LoadingParametersPanel);
            app.xpositioninmmEditFieldLabel.Tooltip = {''};
            app.xpositioninmmEditFieldLabel.Text = 'x - position (in mm)';
            app.xpositioninmmEditField = uieditfield(app.LoadingParametersPanel, 'numeric');
            app.xpositioninmmEditField.Tooltip = {'x-position is positive on right of center point. '; 'Position should not go outside of cross section.'; 'Click on i to know more'};
            app.ypositioninmmEditFieldLabel = uilabel(app.LoadingParametersPanel);
            app.ypositioninmmEditFieldLabel.Tooltip = {''};
            app.ypositioninmmEditFieldLabel.Text = 'y - position (in mm)';
            app.ypositioninmmEditField = uieditfield(app.LoadingParametersPanel, 'numeric');
            app.ypositioninmmEditField.Tooltip = {'y-position is positive above center point. '; 'Position should not go outside of cross section.'; 'Click on i to know more'};
            app.BeamParametersPanel = uipanel(app.InputPanel);
            app.BeamParametersPanel.Title = 'Beam Parameters';
            app.NumberoflayersSpinnerLabel = uilabel(app.BeamParametersPanel);
            app.NumberoflayersSpinnerLabel.Tooltip = {'Number of layers based on different kinds of materials and cross sections'};
            app.NumberoflayersSpinnerLabel.Text = 'Number of layers';
            app.NumberoflayersSpinner = uispinner(app.BeamParametersPanel);
            app.NumberoflayersSpinner.Limits = [0 Inf];
            app.NumberoflayersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberoflayersSpinnerValueChanged, true);
            app.NumberoflayersSpinner.Tooltip = {'Number of layers in the composite beam ( treat same material layer with different cross section as different layer) . Layers are added in bottom to top approach.'};
            app.DetailsofeachlayerPanel = uipanel(app.BeamParametersPanel);
            app.DetailsofeachlayerPanel.Title = 'Details of each layer';
            app.layerHintButton = uibutton(app.BeamParametersPanel, 'push');
            app.layerHintButton.ButtonPushedFcn = createCallbackFcn(app, @layerHintButtonPushed, true);
            app.layerHintButton.Tooltip = {'To know more about layer geometry Click Here'};
            app.layerHintButton.Text = 'i';
            app.CalculateButton = uibutton(app.InputPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = composite_beam_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end