classdef output < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ActualCrossSectionofthebeamTab  matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        BoundingBoxLabel                matlab.ui.control.Label
        HeightinmmLabel                 matlab.ui.control.Label
        height                          matlab.ui.control.NumericEditField
        WidthinmmLabel                  matlab.ui.control.Label
        width                           matlab.ui.control.NumericEditField
        NumberofLayersLabel             matlab.ui.control.Label
        layers                          matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        TransformedCrosssectionTab      matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        Label                           matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        TotalMomentofinertialoftheCrosssectionIzzinmm4EditFieldLabel  matlab.ui.control.Label
        totalIzz                        matlab.ui.control.NumericEditField
        StressanalysisalongxaxisTab     matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        MaximumTensilestressinMPaLabel  matlab.ui.control.Label
        MaximumTensilestressinMPaEditField  matlab.ui.control.NumericEditField
        MinimumTensilestressinMPaEditFieldLabel  matlab.ui.control.Label
        MinimumTensilestressinMPaEditField  matlab.ui.control.NumericEditField
        StresscalculatorforcompositebeamandcombinedloadingLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        %Stores instance of mainApp to resume it when output is closed
        mainApp % Description
                 
    end
    
    methods (Access = public)
        %This is the only function used
        %it processes the data from main app and plots them in 3 graphs
        %accordingly
        
        function initialize(app,handle,layerCount,E,N,b,h,H,b_max,face_color,n,sum,moment,axialLoad,axialX,axialY)
            
            data=uiprogressdlg(app.UIFigure,'Title','Output','Indeterminate',"on",'Message', {' Processing Actual Cross Section of the beam'});
            % Modifications have been done in the code supplied by coder
            % inorder to resolve error in power of 10, as well as errors
            % in arrays not being formed because middle argument( which is
            % the difference between adjacent elements of array[ like 1:0.1:10, 0.1 is middle argument])
            %is greater than the initial and final argument. so, the would
            %work well for 10 m X 10m cross sectiona and not 10mmx10mm
            %cross section. I had asked coder to change his code to take in
            %mm for dimensions and kn m for moment instead of SI units but he asked me to make
            %changes in the app and that's when i came across this
            %problem(that it wont work for SI units as well, power of 10 problem )
            %Due to which app development got extended as i was not sure
            %what was causing the error. In order to avoid any time waste
            %in discussion i proceeded with debugging it myself.
            
            %Assigning values to variables in the function to make it
            %suitable for coder's coded part( which I changed slightly to
            %make it work correctly)
            app.mainApp=handle;
            app.layers.Value=layerCount;
            app.height.Value=H;
            app.width.Value=b_max;
            xlabel(app.UIAxes3,'$Normal\:Stress \; \sigma_{xx} \;(in\: MPa)$','Interpreter','latex');
        
            n_max=max(n);
            %Changing axes of graph 1 and 2 limits based on max dimension
            %of total cross section
            if( b_max>H)
                app.UIAxes.XLim=[-b_max/2-0.5 ,b_max/2+0.5];
                app.UIAxes.YLim=[-b_max/2-0.5 ,b_max/2+2.5];
                app.UIAxes2.XLim=[-n_max*b_max/2-0.5 ,n_max*b_max/2+0.5];
                app.UIAxes2.YLim=[-n_max*b_max/2-0.5 ,n_max*b_max/2+2.5];

            elseif(n_max*b_max>H)                
                app.UIAxes.XLim=[-H/2-0.5,H/2+0.5];
                app.UIAxes.YLim=[-H/2-0.5,H/2+2.5];
                app.UIAxes2.XLim=[-n_max*b_max/2-0.5 ,n_max*b_max/2+0.5];
                app.UIAxes2.YLim=[-n_max*b_max/2-0.5 ,n_max*b_max/2+2.5];
        
            else
                app.UIAxes.XLim=[-H/2-0.5,H/2+0.5];
                app.UIAxes.YLim=[-H/2-0.5,H/2+2.5];
                app.UIAxes2.XLim=[-H/2-0.5,H/2+0.5];
                app.UIAxes2.YLim=[-H/2-0.5,H/2+2.5];

            end
            
            col_list='mbycrgk';
            %Plotting all the layers in graph 1
            for i=1:N
                rectangle(app.UIAxes,'Position',[(-b(i)/2) (sum(i)-(H/2)) b(i) h(i)],'Edgecolor','none', 'Facecolor',col_list(face_color(i))); %For actual cs display
            end
            
            rectangle(app.UIAxes,'Position',[(-b_max/2) (-H/2) b_max H],'FaceColor','none');
            drawrectangle(app.UIAxes,'Position',[(-b_max/2) (-H/2) b_max H],'Color','k','Label','Bounding Box','InteractionsAllowed','none');
            center=plot(app.UIAxes,0,0,'Color',[0.4 0.9 0.6],'Marker','.','MarkerSize',15);
            legend(app.UIAxes,center,'Center of bounding box');
            
            %Making transformed cross section with respect to first layer
            data.Message= {'Transforming Cross Section of beam with respect to layer 1'};
            xtransform = zeros(1,4*N);
            ytransform = zeros(1,4*N);
            for i=2:2:(2*N)
                xtransform(i) = n(i/2)*(b(i/2)/2);
                xtransform(i+1) = n(i/2)*(b(i/2)/2);
            end
            for i=((2*N)+2):2:((4*N)-2)
                xtransform(i) = -n(((2-i)/2)+(2*N))*(b(((2-i)/2)+(2*N))/2);
                xtransform(i+1) = -n(((2-i)/2)+(2*N))*(b(((2-i)/2)+(2*N))/2);
            end
            xtransform(1) = -n(1)*(b(1)/2);
            xtransform(4*N) = -n(1)*(b(1)/2);
            
            ytransform(1) = -H/2;
            ytransform(2) = -H/2;
            ytransform((2*N)+1) = (H/2);
            ytransform((2*N)+2) = (H/2);
            for i=3:2:((2*N)-1)
                ytransform(i) = sum(((i-3)/2)+2)-(H/2);
                ytransform(i+1) = sum(((i-3)/2)+2)-(H/2);
            end
            
            for i=((2*N)+3):2:((4*N)-1)
                ytransform(i) = sum(((3-i)/2)+(2*N))-(H/2);
                ytransform(i+1) = sum(((3-i)/2)+(2*N))-(H/2);
            end
            %Plotting transformed cross section
            transform = polyshape(xtransform, ytransform);%Figure 2 transformed
            plot(app.UIAxes2,transform)
                        
            y=zeros(1,N);
            y(1) = (h(1)/2)-(H/2);
            A=zeros(1,N);
            A(1) = b(1)*h(1);
            %Centroid for each rectangular part in ( like I has 3
            %rectangles) of cross section
            plot(app.UIAxes2,0,(h(1)/2)-(H/2),'r.','MarkerSize',15)
            for i=2:N
                plot(app.UIAxes2,0,(sum(i)+(h(i)/2)-(H/2)),'r.','MarkerSize',15); % plot centeroid of each cs
                y(i) = sum(i)+(h(i)/2)-(H/2);
                A(i) = n(i)*b(i)*h(i);
            end
            
            sumAy=0;
            sumA=0;
            for i=1:N
                sumAy = sumAy + y(i)*A(i);
                sumA = sumA + A(i);
            end
            %Plotting centroid
            yc = sumAy/sumA;
            centroid=plot(app.UIAxes2,0,yc,'k.','MarkerSize',15);
            legend(app.UIAxes2,centroid,'Centroid of Beam Cross Section')
            if axialLoad~=0
                loadLoc=plot(app.UIAxes2,axialX,axialY,'g.','MarkerSize',15);
                legend(app.UIAxes2,loadLoc,'Location of Axial Load')
            end
            I=zeros(1,N); % Matrix of individual Izz
            sumIzz=0;
            for i=1:N
                I(i) = ((n(i)*b(i)*(h(i)^3))/12) + (A(i)*(y(i)-yc)^2);
                sumIzz = sumIzz + I(i);
            end
            
            %Final centroidal moment of inertia:
            Itr = sumIzz;
            app.totalIzz.Value=Itr;%In mm4
            
            Itr=Itr*(10^(-12));%converting to si unit
            data.Message= {'Analysing the flexural stress in the beam'};
            M=moment*1000;%Bending moment
            P=axialLoad*1000;% axial eccentric load
            H=H/1000; %units converted to SI
            sum=sum./1000;
            yc=yc/1000;
            sumA=sumA/(10^6);
            
            if P==0
                %Only bending moment 
                for i=1:N-1
                    %Previous code : y=((sum(i)-(H/2)) : 0.1 : (sum(i+1)-(H/2)));
                    %Similar problems in rest of similar lines
                    y = ((sum(i)-(H/2)) : (sum(i+1)-sum(i))/20 : (sum(i+1)-(H/2)));
                    x = -((M*n(i)).*(y-yc))./Itr;
                    x=x./(10^6);
                    %Plotting stress line
                    plot(app.UIAxes3,x,y.*1000,'LineWidth',3,'Color','b');
                    
                    y = (sum(i)-(H/2) ): (sum(i+1)-sum(i))/20  :(sum(i+1)-(H/2));
                    x = -((M*n(i)).*(y-yc))./Itr;
                    x=x./(10^6);
                    %ploting arrows in the diagram
                    quiver(app.UIAxes3,x.*0,y.*1000,x,y.*0,0,'ShowArrowHead','off','Color','r');
                    scatter(app.UIAxes3,x,y.*1000,14,"Marker","d","MarkerFaceColor","r")
%                      Extra code whose working was not explained since no
%                      comments were there in the code
%                       x = (-((abs(M)*n(N))*((H/2)-yc))/Itr : 0.1 : ((abs(M)*n(N))*((H/2)-yc))/Itr);
%                      y = sum(i+1)-(H/2) + 0.*x;
%                      x=x./(10^6);
%                      plot(app.UIAxes3,x,y,'k');

                end
                y = (sum(N)-(H/2) ): (H-sum(N))/20:( (H/2));
               %Plotting last layer 
                x = -((M*n(N)).*(y-yc))./Itr;
                x=x./(10^6);
                pl=plot(app.UIAxes3,x,y.*1000,'LineWidth',3,'Color','b');
                
                y = (sum(N)-(H/2) ): (H-sum(N))/20 :( (H/2));
                x = -((M*n(N)).*(y-yc))./Itr;
                x=x./(10^6);
                quiver(app.UIAxes3,x.*0,y.*1000,x,y.*0,0,'ShowArrowHead','off','Color','r');
                scatter(app.UIAxes3,x,y.*1000,14,"Marker","d","MarkerFaceColor","r")
                
                y = (-(H*1000/2) : 0.1 : (H*1000/2));
                x=0.*y;
                plot(app.UIAxes3,x,y,'-k');
                                
                %Maximum and minimum stress
                sigma1 = -((M*n(N)).*((H/2)-yc))/Itr;
                sigma2 = -((M*n(1)).*(-(H/2)-yc))/Itr;
                if sigma1>sigma2
                    sigma_max = sigma1;
                    sigma_min = sigma2;
                else
                    sigma_max = sigma2;
                    sigma_min = sigma1;
                end
                
                elseif M==0
                    %only axial eccentric loading
                    hori=axialX/1000;
                    verti = axialY/1000;
                    d = sqrt((abs(yc-verti))^2 + (hori)^2);
                    
                    for i=1:N-1
                        y = ((sum(i)-(H/2)) :(sum(i+1)-sum(i))/20  : (sum(i+1)-(H/2)));
                        x = (P/sumA) - (((P*d*n(i)).*(y-yc))./Itr); %Adding the axial stress formula to the flexure formula
                        x=x./(10^6);
                        %Ploting stress line
                        plot(app.UIAxes3,x,y.*1000,'LineWidth',3,'Color','b');
                        %ploting arrows from y axis to stress line
                        y = (sum(i)-(H/2) ): (sum(i+1)-sum(i))/20 :(sum(i+1)-(H/2));
                        x = (P/sumA) - (((P*d*n(i)).*(y-yc))./Itr); 
                        x=x./(10^6);
                       quiver(app.UIAxes3,x.*0,y.*1000,x,y.*0,0,'ShowArrowHead','off','Color','r');
                        scatter(app.UIAxes3,x,y.*1000,14,"Marker","d","MarkerFaceColor","r")

%                         x = (-((abs(P)/sumA) - (((abs(P)*d*n(N))*((H/2)-yc))/Itr)) : 0.1 : ((abs(P)/sumA) - (((abs(P)*d*n(N))*((H/2)-yc))/Itr)));
%                         y = sum(i+1)-(H/2) + 0.*x;
%                         x=x./(10^6);
%                         plot(app.UIAxes3,x,y,'k'); %Plotting the stress distribution
                    end
                    %plotting last layer
                    y = (sum(N)-(H/2) : (H-sum(N))/20: (H/2));
                    x = (P/sumA) - (((P*d*n(N)).*(y-yc))./Itr);
                    x=x./(10^6);
                    pl=plot(app.UIAxes3,x,y.*1000,'LineWidth',3,'Color','b');
                    
                    
                    y = (sum(N)-(H/2) :(H-sum(N))/20: (H/2));
                    x = (P/sumA) - (((P*d*n(N)).*(y-yc))./Itr); 
                    x=x./(10^6);
                    quiver(app.UIAxes3,x.*0,y.*1000,x,y.*0,0,'ShowArrowHead','off','Color','r');
                    scatter(app.UIAxes3,x,y.*1000,14,"Marker","d","MarkerFaceColor","r")
                    y = (-(H*1000/2) : 0.1 : (H*1000/2));
                    x=0.*y;
                    plot(app.UIAxes3,x,y,'k');
                    %Finding the maximum (tensile) and the minimum (compressive) stress: 
                    sigma1 = (P/sumA)-(((P*d*n(N)).*((H/2)-yc))/Itr);
                    sigma2 = (P/sumA)-(((P*d*n(1)).*(-(H/2)-yc))/Itr);
                    if sigma1 > sigma2
                        sigma_max = sigma1;
                        sigma_min = sigma2;
                    else
                        sigma_max = sigma2;
                        sigma_min = sigma1;
                    end
  
            else
                %Case of both bending and axial eccentric loading
                    hori = axialX/1000;
                    verti = axialY/1000;
                    d = sqrt((abs(yc-verti))^2 + (hori)^2);
                    for i=1:N-1
                        y = ((sum(i)-(H/2)) :  (sum(i+1)-sum(i))/20 : (sum(i+1)-(H/2)));
                        x = (P/sumA) - (((((P*d)+M)*n(i)).*(y-yc))./Itr); %Total moment would be Pd+M 
                        x=x./(10^6);
                        %plotting stress line
                        plot(app.UIAxes3,x,y.*1000,'LineWidth',3,'Color','b');
                        
                        y = (sum(i)-(H/2) ): (sum(i+1)-sum(i))/20  :(sum(i+1)-(H/2));
                        x = (P/sumA) - (((((P*d)+M)*n(i)).*(y-yc))./Itr); 
                        x=x./(10^6);
                        %plotting arrows in the diagram
                        quiver(app.UIAxes3,x.*0,y.*1000,x,y.*0,0,'ShowArrowHead','off','Color','r');
                        scatter(app.UIAxes3,x,y.*1000,14,"Marker","d","MarkerFaceColor","r")
                        
%                           x = (-((abs(P)/sumA) - (((((abs(P)*d)+abs(M))*n(N))*((H/2)-yc))/Itr)) : 0.1 : ((abs(P)/sumA) - (((((abs(P)*d)+abs(M))*n(N))*((H/2)-yc))/Itr)));
%                         y = sum(i+1)-(H/2) + 0.*x;
%                         x=x./(10^6);
%                         plot(app.UIAxes3,x,y,'k');
                        
                    end
                    %plotting last layer
                    y = (sum(N)-(H/2) : (H-sum(N))/20  : (H/2));
                    x = (P/sumA) - (((((P*d)+M)*n(N)).*(y-yc))./Itr);
                    x=x./(10^6);
                    pl=plot(app.UIAxes3,x,y.*1000,'LineWidth',3,'Color','b');
                   
                    
                    y = (sum(N)-(H/2) : (H-sum(N))/20 : (H/2));
                    x = (P/sumA) - (((((P*d)+M)*n(N)).*(y-yc))./Itr);
                    x=x./(10^6);
                    quiver(app.UIAxes3,x.*0,y.*1000,x,y.*0,0,'ShowArrowHead','off','Color','r');
                    scatter(app.UIAxes3,x,y.*1000,14,"Marker","d","MarkerFaceColor","r")
                    y = (-(H*1000/2) : 0.1 : (H*1000/2));
                    x=0.*y;
                    plot(app.UIAxes3,x,y,'k');
                    
                    
                    %Finding the maximum (tensile) and the minimum (compressive) stress:
                    sigma1 = (P/sumA)-(((((P*d)+M)*n(N)).*((H/2)-yc))/Itr);
                    sigma2 = (P/sumA)-(((((P*d)+M)*n(1)).*(-(H/2)-yc))/Itr);
                    if sigma1> sigma2
                        sigma_max = sigma1;
                        sigma_min = sigma2;
                    else
                        sigma_max = sigma2;
                        sigma_min = sigma1;
                    end          
            end
            if sigma_min<0
                app.MinimumTensilestressinMPaEditFieldLabel.Text='Maximum Compressive stress (in MPa) :';
            else
                app.MinimumTensilestressinMPaEditFieldLabel.Text='Minimum Tensile stress (in MPa) :';
            end
            
            p=plot(app.UIAxes3,[sigma_min/(10^6), sigma_max/(10^6)],[yc*1000 yc*1000],'Color',[0.494 0.184 0.556]);
            %applying legends
            legend(app.UIAxes3,[p,pl],'Neutral axis','Stress Line')
            app.MaximumTensilestressinMPaEditField.Value=sigma_max/(10^6);
            app.MinimumTensilestressinMPaEditField.Value=sigma_min/(10^6);
            close(data);
       end
            
   end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %resuming main app when output window is closed
            uiresume(app.mainApp);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ActualCrossSectionofthebeamTab = uitab(app.TabGroup);
            app.ActualCrossSectionofthebeamTab.Title = 'Actual Cross Section of the beam';
            app.UIAxes = uiaxes(app.ActualCrossSectionofthebeamTab);
            title(app.UIAxes, 'Actual Cross Section of your beam')
            xlabel(app.UIAxes, 'X axis (in mm)')
            ylabel(app.UIAxes, 'Y axis (in mm)')
            app.BoundingBoxLabel = uilabel(app.ActualCrossSectionofthebeamTab);
            app.BoundingBoxLabel.Text = 'Bounding Box :';
            app.HeightinmmLabel = uilabel(app.ActualCrossSectionofthebeamTab);
            app.HeightinmmLabel.Text = 'Height (in mm):';
            app.height = uieditfield(app.ActualCrossSectionofthebeamTab, 'numeric');
            app.height.Editable = 'off';
            app.WidthinmmLabel = uilabel(app.ActualCrossSectionofthebeamTab);
            app.WidthinmmLabel.Text = 'Width (in mm):';
            app.width = uieditfield(app.ActualCrossSectionofthebeamTab, 'numeric');
            app.width.Editable = 'off';
            app.NumberofLayersLabel = uilabel(app.ActualCrossSectionofthebeamTab);
            app.NumberofLayersLabel.Text = 'Number of Layers :';
            app.layers = uieditfield(app.ActualCrossSectionofthebeamTab, 'numeric');
            app.layers.Editable = 'off';
            app.Label_2 = uilabel(app.ActualCrossSectionofthebeamTab);
            app.Label_2.Text = {'Different colors are just meant to differentiate between adjacent layers of cross section '; 'of the beam. They have no physical significance'};
            app.TransformedCrosssectionTab = uitab(app.TabGroup);
            app.TransformedCrosssectionTab.Title = 'Transformed Cross section';
            app.UIAxes2 = uiaxes(app.TransformedCrosssectionTab);
            title(app.UIAxes2, 'Transformed Cross section with respect to layer 1')
            xlabel(app.UIAxes2, 'X axis (in mm)')
            ylabel(app.UIAxes2, 'Y axis (in mm)')
            app.Label = uilabel(app.TransformedCrosssectionTab);
            app.Label.Text = {'The red dots in the graph '; 'represent centroid of all the '; 'rectangles (breaking T and I cross '; 'section into rectangles as well)'};
            app.Label_3 = uilabel(app.TransformedCrosssectionTab);
            app.Label_3.Text = {'This graph represents the transformed '; 'cross section of the beam with '; 'respect to first layer.'; ''; 'All widths have been changed based '; 'on ratio of elastic modulus of that '; 'layer with the '; ' elastic modulus of first layer '};
            app.TotalMomentofinertialoftheCrosssectionIzzinmm4EditFieldLabel = uilabel(app.TransformedCrosssectionTab);
            app.TotalMomentofinertialoftheCrosssectionIzzinmm4EditFieldLabel.Text = 'Total Moment of inertial of the Cross section ( Izz (in mm4)) :';
            app.totalIzz = uieditfield(app.TransformedCrosssectionTab, 'numeric');
            app.totalIzz.Editable = 'off';
            app.StressanalysisalongxaxisTab = uitab(app.TabGroup);
            app.StressanalysisalongxaxisTab.Title = 'Stress analysis along x axis';
            app.UIAxes3 = uiaxes(app.StressanalysisalongxaxisTab);
            title(app.UIAxes3, 'Stress analysis along x axis varying with y')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y axis (in mm)')
            app.MaximumTensilestressinMPaLabel = uilabel(app.StressanalysisalongxaxisTab);
            app.MaximumTensilestressinMPaLabel.Text = 'Maximum Tensile stress (in MPa) :';
            app.MaximumTensilestressinMPaEditField = uieditfield(app.StressanalysisalongxaxisTab, 'numeric');
            app.MaximumTensilestressinMPaEditField.Editable = 'off';
            app.MinimumTensilestressinMPaEditFieldLabel = uilabel(app.StressanalysisalongxaxisTab);
            app.MinimumTensilestressinMPaEditFieldLabel.Text = 'Minimum Tensile stress (in MPa) :';
            app.MinimumTensilestressinMPaEditField = uieditfield(app.StressanalysisalongxaxisTab, 'numeric');
            app.MinimumTensilestressinMPaEditField.Editable = 'off';
            app.StresscalculatorforcompositebeamandcombinedloadingLabel = uilabel(app.UIFigure);
            app.StresscalculatorforcompositebeamandcombinedloadingLabel.Text = 'Stress calculator for composite beam and combined loading';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = output

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end